classdef ege < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        result        matlab.ui.control.EditField
        exam3         matlab.ui.control.EditField
        exam2         matlab.ui.control.EditField
        exam1         matlab.ui.control.EditField
        Label_5       matlab.ui.control.Label
        Button        matlab.ui.control.Button
        sum           matlab.ui.control.NumericEditField
        Label_4       matlab.ui.control.Label
        EditField3_2  matlab.ui.control.EditField
        EditField2_2  matlab.ui.control.EditField
        EditField_5   matlab.ui.control.EditField
        EditField_3   matlab.ui.control.EditField
        EditField_2   matlab.ui.control.EditField
        EditField     matlab.ui.control.EditField
        Label_3       matlab.ui.control.Label
        Label_2       matlab.ui.control.Label
        Label         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ex1 = str2double(app.exam1.Value);
            ex2 = str2double(app.exam2.Value);
            ex3 = str2double(app.exam3.Value);
            s = ex1+ex2+ex3;
            app.sum.Value = s;
            if ex1 < 101 && ex2 < 101 && ex3 < 101 && ex1*ex2*ex3 >= 0
                if s > 260
                    app.result.Value = 'Вы поступили!';
                else s >= 0 && s <= 260
                    app.result.Value = 'Вы не поступили';
            else
                app.result.Value = 'Данные введены некорректно';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия Имя Отчество';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предметы ЕГЭ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Количество баллов';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Placeholder = 'Фамилия';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Placeholder = 'Имя';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Placeholder = 'Отчество';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Placeholder = 'Предмет 1';
            app.EditField2_2 = uieditfield(app.UIFigure, 'text');
            app.EditField2_2.Placeholder = 'Предмет 2';
            app.EditField3_2 = uieditfield(app.UIFigure, 'text');
            app.EditField3_2.Placeholder = 'Предмет 3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Сумма баллов';
            app.sum = uieditfield(app.UIFigure, 'numeric');
            app.sum.Editable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычислить сумму';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Проходной балл: 260';
            app.exam1 = uieditfield(app.UIFigure, 'text');
            app.exam1.Placeholder = '0';
            app.exam2 = uieditfield(app.UIFigure, 'text');
            app.exam2.Placeholder = '0';
            app.exam3 = uieditfield(app.UIFigure, 'text');
            app.exam3.Placeholder = '0';
            app.result = uieditfield(app.UIFigure, 'text');
            app.result.Editable = 'off';
            app.result.Placeholder = 'Ваш результат';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ege

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end