classdef trigonom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlottingButton  matlab.ui.control.Button
        cosxCheckBox    matlab.ui.control.CheckBox
        sinxCheckBox    matlab.ui.control.CheckBox
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlottingButton
        function PlottingButtonPushed(app, event)
            x = 0:0.01:4*pi;
            ysin = sin(x);
            ycos = cos(x);
            hold(app.UIAxes, "off")
            if app.sinxCheckBox.Value == 1
                plot(app.UIAxes, x, ysin)
                hold(app.UIAxes, "on")
            end
            if app.cosxCheckBox.Value == 1
                plot(app.UIAxes, x, ycos)
                hold(app.UIAxes, "on")
            end
            if app.sinxCheckBox.Value == 0 && app.cosxCheckBox.Value == 0
                plot(app.UIAxes,0,0)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.sinxCheckBox = uicheckbox(app.UIFigure);
            app.sinxCheckBox.Text = 'sin(x)';
            app.cosxCheckBox = uicheckbox(app.UIFigure);
            app.cosxCheckBox.Text = 'cos(x)';
            app.PlottingButton = uibutton(app.UIFigure, 'push');
            app.PlottingButton.ButtonPushedFcn = createCallbackFcn(app, @PlottingButtonPushed, true);
            app.PlottingButton.Text = 'Plotting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trigonom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end